classdef NumericalMethodsSolverapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        NumberofstepsEditField        matlab.ui.control.NumericEditField
        NumberofstepsEditFieldLabel   matlab.ui.control.Label
        StepsizeEditField             matlab.ui.control.NumericEditField
        StepsizeEditFieldLabel        matlab.ui.control.Label
        InitialYEditField             matlab.ui.control.NumericEditField
        InitialYEditFieldLabel        matlab.ui.control.Label
        InitialGuessx1EditField       matlab.ui.control.NumericEditField
        InitialGuessx1EditFieldLabel  matlab.ui.control.Label
        LowerboundbEditField          matlab.ui.control.NumericEditField
        LowerboundbEditFieldLabel     matlab.ui.control.Label
        LowerboundaEditField          matlab.ui.control.NumericEditField
        LowerboundaEditFieldLabel     matlab.ui.control.Label
        fxEditField_2                 matlab.ui.control.EditField
        fxEditField_2Label            matlab.ui.control.Label
        fxEditField                   matlab.ui.control.EditField
        fxEditFieldLabel              matlab.ui.control.Label
        UITable                       matlab.ui.control.Table
        DisplayanswerTextArea         matlab.ui.control.TextArea
        DisplayanswerTextAreaLabel    matlab.ui.control.Label
        CalculateButton               matlab.ui.control.Button
        toleranceEditField            matlab.ui.control.NumericEditField
        toleranceEditFieldLabel       matlab.ui.control.Label
        InitialxEditField             matlab.ui.control.NumericEditField
        InitialxLabel                 matlab.ui.control.Label
        MethodDropDown                matlab.ui.control.DropDown
        MethodDropDownLabel           matlab.ui.control.Label
        NUMERICALMETHODSSOLVERLabel   matlab.ui.control.Label
        UIAxes                        matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
          method = app.MethodDropDown.Value;
          if strcmp(method,'Newton-Raphson')
    
            % Get values from the app
            x=app.InitialxEditField.Value;
            tolerance = app.toleranceEditField.Value;

            % Newton-Raphson settings
            error =1;
            iteration =0

            % Create storage for table
            results=[];

            % Newton-Raphson loop
           while error>tolerance
           iteration=iteration+1;

           % f(x) =x^2-2
            fx=x^2-2

            % f'(x) =2x
            dfx=2*x;

            % Newton-Raphson formula
            xnew=x-fx/dfx

            % Calculate the error
            error=abs(xnew-x);

            % Store results
            results(iteration,:)=[iteration,xnew,xnew^2-2,error];

            % update x
            x=xnew

           end
           % Display answer
            app.DisplayanswerTextArea.Value={['Root=',num2str(x)] 
                ['Iterations=',num2str(iteration)]}; 
            
            % Display results in the table
            app.UITable.Data = results;

            cla(app.UIAxes);
            plot(app.UIAxes,results(:,1)),
            results(:,4),('r-o');

            xlabel(app.UIAxes,'Iteration');
            ylabel(app.UIAxes,'Error');
            title(app.UIAxes,'Newton-Raphson Convergence');
            grid(app.UIAxes,'on');


            elseif strcmp(method,'Bisection')
                a=1;
                b=2;
                 tolerance = app.toleranceEditField.Value;

                  error =1;
                  iteration =0
                   results=[];

                    while error>tolerance

                    iteration=iteration+1;
                    c=(a+b)/2;

                    fa = a^3-a-2;
                    fc = c^3-c-2;

                    error = abs(b-a)/2;
                    results(iteration,:)=[iteration,a,b,c,error];
                     if fa*fc<0
                         b=c;
                     else
                         a=c;
                     end
                    end
                     app.DisplayanswerTextArea.Value={['Root=',num2str(c)] 
                ['Iterations=',num2str(iteration)]};

                app.UITable.Data = results;

             cla(app.UIAxes);
            plot(app.UIAxes,results(:,1)),
            results(:,5),('g-o');

            xlabel(app.UIAxes,'Iteration');
            ylabel(app.UIAxes,'Error');
            title(app.UIAxes,'Bisection Convergence');
            grid(app.UIAxes,'on');


        elseif strcmp(method,'Secant')
               x=app.InitialxEditField.Value;
               x1=app.InitialGuessx1EditField.Value;
                tolerance = app.toleranceEditField.Value;
               
                 error =1;
                  iteration =0
                   results=[];
                   
                    while error>tolerance
                        f = x^3-x-2;
                        f1 = x1^3-x1-2;

                        if f1 -f ==0
                            uialert(app.UIFigure,['Division by zero.' ...
                                'Choose different initial guesses.'], ...
                                'Secant Metod Error');
                            return;
                            
                        end
                    

                        x2 =x1-f1*(x1-x)/(f1-f);
                        error = abs(x2-x1);
                        iteration=iteration+1;
                         results(iteration,:)=[iteration,x,x1,x2,error];
                         x=x1;
                         x1=x2;
                    end
          

                    app.DisplayanswerTextArea.Value={['Root=',num2str(x1)] 
                ['Iterations=',num2str(iteration)]};

                app.UITable.Data = results;

            cla(app.UIAxes);
            plot(app.UIAxes,results(:,1)),
            results(:,5),('r-o');

            xlabel(app.UIAxes,'Iteration');
            ylabel(app.UIAxes,'Error');
            title(app.UIAxes,'Secant Convergence');
            grid(app.UIAxes,'on');


                 elseif strcmp(method,'Runge-Kutta')
                     x=app.InitialxEditField.Value;
                     y=app.InitialYEditField.Value;
                     h=app.StepsizeEditField.Value;
                     n=app.NumberofstepsEditField.Value;

                     results=zeros(n+1,3);
                     results(1,:)=[0,x,y];
       
                     for i=1:n
                         k1=h*(x+y);
                         k2=h*((x+h/2)+(y+k1/2));
                          k3=h*((x+h/2)+(y+k2/2));
                           k4=h*((x+h)+(y+k3));

                           y= y+(k1+2*k2+2*k3+k4)/6;
                           x=x+h;
                           results(i+1,:)=[i,x,y];
                     end
                     app.UITable.Data=results;
                     app.DisplayanswerTextArea.Value={sprintf('Finalx=%.4f',x)
                       sprintf('Finaly=%.4f',y) 
                       sprintf('Number of steps =%.d',n)};

           cla(app.UIAxes);
            plot(app.UIAxes,results(:,2)),
            results(:,3),('b-o');

            xlabel(app.UIAxes,'x');
            ylabel(app.UIAxes,'y');
            title(app.UIAxes,'Runge-Kutta Solution');
            grid(app.UIAxes,'on');
            
       
          end


        end

        % Value changed function: MethodDropDown
        function MethodDropDownValueChanged(app, event)
            method = app.MethodDropDown.Value;
            switch method
                case'Newton-Raphson'
                    app.fxEditField.Value='x^2-2';
                    app.InitialxEditField.Value=1
                    app.InitialxEditField.Visible='on';
                    app.toleranceEditField.Visible='on';
                    app.UITable.ColumnName={'Iteration','x','f(x)','Error'};

                case'Bisection'
                    app.fxEditField.Value='x^3-x-2';
                    app.InitialxEditField.Value=1
                    app.InitialxEditField.Visible='on';
                    app.toleranceEditField.Visible='on';
                    app.UITable.ColumnName={'Iteration','a','b','c','Error'};
                    

                case'Secant'
                    app.fxEditField.Value='x^3-x-2';
                    app.InitialxEditField.Value=1
                    app.InitialxEditField.Visible='on';
                    app.toleranceEditField.Visible='on';
                    app.UITable.ColumnName={'Iteration','x','x1','x2','Error'};

                case'Runge-Kutta'
                    app.fxEditField.Value='x+y';
                    app.InitialxEditField.Value=0
                    app.InitialxEditField.Visible='on';
                    app.toleranceEditField.Visible='on';
                    app.UITable.ColumnName={'Step','x','y'};
            end


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NUMERICALMETHODSSOLVERLabel = uilabel(app.UIFigure);
            app.NUMERICALMETHODSSOLVERLabel.Text = 'NUMERICAL METHODS SOLVER';
            app.MethodDropDownLabel = uilabel(app.UIFigure);
            app.MethodDropDownLabel.Text = 'Method';
            app.MethodDropDown = uidropdown(app.UIFigure);
            app.MethodDropDown.Items = {'Newton-Raphson', 'Bisection', 'Secant', 'Runge-Kutta'};
            app.MethodDropDown.ValueChangedFcn = createCallbackFcn(app, @MethodDropDownValueChanged, true);
            app.MethodDropDown.Value = 'Bisection';
            app.InitialxLabel = uilabel(app.UIFigure);
            app.InitialxLabel.Text = 'Initial x';
            app.InitialxEditField = uieditfield(app.UIFigure, 'numeric');
            app.toleranceEditFieldLabel = uilabel(app.UIFigure);
            app.toleranceEditFieldLabel.Text = 'tolerance';
            app.toleranceEditField = uieditfield(app.UIFigure, 'numeric');
            app.toleranceEditField.Value = 0.0001;
            app.CalculateButton = uibutton(app.UIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.DisplayanswerTextAreaLabel = uilabel(app.UIFigure);
            app.DisplayanswerTextAreaLabel.Text = 'Display answer';
            app.DisplayanswerTextArea = uitextarea(app.UIFigure);
            app.DisplayanswerTextArea.Value = {'Root ='; 'Iterations ='};
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Step'; 'x'; 'y'};
            app.UITable.RowName = {};
            app.fxEditFieldLabel = uilabel(app.UIFigure);
            app.fxEditFieldLabel.Text = 'f(x)';
            app.fxEditField = uieditfield(app.UIFigure, 'text');
            app.fxEditField.Value = 'x+y';
            app.fxEditField_2Label = uilabel(app.UIFigure);
            app.fxEditField_2Label.Text = 'f''(x)';
            app.fxEditField_2 = uieditfield(app.UIFigure, 'text');
            app.fxEditField_2.Value = '2*x';
            app.LowerboundaEditFieldLabel = uilabel(app.UIFigure);
            app.LowerboundaEditFieldLabel.Text = 'Lower bound(a)';
            app.LowerboundaEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerboundaEditField.Value = 1;
            app.LowerboundbEditFieldLabel = uilabel(app.UIFigure);
            app.LowerboundbEditFieldLabel.Text = 'Lower bound(b)';
            app.LowerboundbEditField = uieditfield(app.UIFigure, 'numeric');
            app.LowerboundbEditField.Value = 2;
            app.InitialGuessx1EditFieldLabel = uilabel(app.UIFigure);
            app.InitialGuessx1EditFieldLabel.Text = 'Initial Guess (x1)';
            app.InitialGuessx1EditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialGuessx1EditField.Value = 2;
            app.InitialYEditFieldLabel = uilabel(app.UIFigure);
            app.InitialYEditFieldLabel.Text = 'Initial Y';
            app.InitialYEditField = uieditfield(app.UIFigure, 'numeric');
            app.InitialYEditField.Value = 1;
            app.StepsizeEditFieldLabel = uilabel(app.UIFigure);
            app.StepsizeEditFieldLabel.Text = 'Stepsize';
            app.StepsizeEditField = uieditfield(app.UIFigure, 'numeric');
            app.StepsizeEditField.Value = 0.1;
            app.NumberofstepsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofstepsEditFieldLabel.Text = 'Numberofsteps';
            app.NumberofstepsEditField = uieditfield(app.UIFigure, 'numeric');
            app.NumberofstepsEditField.Value = 10;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = NumericalMethodsSolverapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end